classdef garmin_frontend < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SendMessageEditField            matlab.ui.control.EditField
        SendMessageEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        VolumedBKnob                    matlab.ui.control.Knob
        VolumedBKnobLabel               matlab.ui.control.Label
        Meter                           audio.ui.control.Meter
        UpdateButton                    slrealtime.ui.control.UpdateButton
        ParameterTable                  slrealtime.ui.control.ParameterTable
        CurrentDiverFeedbackGauge       matlab.ui.control.SemicircularGauge
        CurrentDiverFeedbackGaugeLabel  matlab.ui.control.Label
        setDataMatrixEditField          matlab.ui.control.EditField
        setDataMatrixEditFieldLabel     matlab.ui.control.Label
        loadwavfileButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        Arduino % Description
        fileName % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ParameterTable, setDataMatrixEditField
        function setDataMatrixEditFieldValueChanged(app, event)
            app.fileName = app.setDataMatrixEditField.Value;
            app.fileName = app.fileName + ".mat"
            save(app.fileName)
        end

        % Button pushed function: loadwavfileButton
        function loadwavfileButtonPushed(app, event)
            load(app.fileName)
            [file,path] = uigetfile('*.wav')
            [audioData,fs] = audioread(file);
            soundsc(audioData,fs)
            TotalTime = length(audioData)./fs
            %delay(TotalTime)
            pause(TotalTime)
            [audioData1,fs1] = audioread("test1.wav");
            soundsc(audioData1,fs1)

            a =  arduino;
            dataValue = readVoltage(a, 'A0')
            if (dataValue <= 1.25)
                rating = "worst";
            end 
            if (dataValue >= 1.25 && dataValue <= 2.5)
                rating = "bad";
            end 
            if (dataValue >= 2.5 && dataValue <= 3.75)
                rating = "better";
            end 
            if (dataValue >= 3.75)
                rating = "best";
            end 
            dataSet = [file, rating]
            a = cat(1,a, dataSet)
            save(app.fileName,'-append','a')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadwavfileButton = uibutton(app.UIFigure, 'push');
            app.loadwavfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadwavfileButtonPushed, true);
            app.loadwavfileButton.Text = 'load .wav file ';
            app.setDataMatrixEditFieldLabel = uilabel(app.UIFigure);
            app.setDataMatrixEditFieldLabel.Text = 'setDataMatrix';
            app.setDataMatrixEditField = uieditfield(app.UIFigure, 'text');
            app.setDataMatrixEditField.ValueChangedFcn = createCallbackFcn(app, @setDataMatrixEditFieldValueChanged, true);
            app.CurrentDiverFeedbackGaugeLabel = uilabel(app.UIFigure);
            app.CurrentDiverFeedbackGaugeLabel.Text = 'Current Diver Feedback';
            app.CurrentDiverFeedbackGauge = uigauge(app.UIFigure, 'semicircular');
            app.CurrentDiverFeedbackGauge.Limits = [0 5];
            app.ParameterTable = slrealtime.ui.control.ParameterTable(app.UIFigure);
            app.ParameterTable.SizeChangedFcn = createCallbackFcn(app, @setDataMatrixEditFieldValueChanged, true);
            app.UpdateButton = slrealtime.ui.control.UpdateButton(app.UIFigure);
            app.UpdateButton.UpdateText = 'Upload Audio File';
            app.Meter = uiaudiometer(app.UIFigure);
            app.Meter.Limits = [0 80];
            app.VolumedBKnobLabel = uilabel(app.UIFigure);
            app.VolumedBKnobLabel.Text = 'Volume (dB)';
            app.VolumedBKnob = uiknob(app.UIFigure, 'continuous');
            app.VolumedBKnob.Limits = [0 70];
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SendMessageEditFieldLabel = uilabel(app.UIFigure);
            app.SendMessageEditFieldLabel.Text = 'Send Message';
            app.SendMessageEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = garmin_frontend

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end